classdef IntellEQApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        LoadFileButton        matlab.ui.control.Button
        filenameLabel         matlab.ui.control.Label
        PlayButton            matlab.ui.control.Button
        StopButton            matlab.ui.control.Button
        RUNButton             matlab.ui.control.Button
        CenterPanel           matlab.ui.container.Panel
        IdentifiedGenreLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        genreLabel            matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Fs = 44100; % sample rate
        audioIn % file to be processed
    end


    methods (Access = private)
        
        function [filename,audio,Fs] = prepareAudio(app, file, path)
            %prepareAudio Creates a vertical array of mono or stereo audio based
            %             on the file chosen by user
            
            fileToRead = strcat(path,file);
            [in, Fs] = audioread(fileToRead);
            filename = file;
            audio = in;
        end
        
        
        function netInputArray = processBands(app, in, Fs)
            
            if Fs < 22050
                in = upsample(in,2);   % upsample audio
                Fs = Fs * 2;
            end
            
            % filter parameters
            Nyq = Fs/2;
            order = 2;                             % filter order
            numOfBands = 5;
            freq = [20, 250, 500, 2000, 6000, 20000];
               
            N = length(in);
            mono = zeros(N,1);
            for n = 1:N
                mono(n,1) = in(n,1) + in(n,2);
            end
            
            in = mono;
            
            % Calculate RMS of whole file
            sigSquaredIn = in .^2;
            sigMeanIn = (1/N) * sum(sigSquaredIn);
            sigRootSquaredIn = sqrt(sigMeanIn);
            rmsIn = sigRootSquaredIn;
            
            signalBands = zeros(N, numOfBands);  % initialize array for storage of filtered signals
            bandAmplitudes = zeros(1, numOfBands+1);
             
            for band = 1:numOfBands
                Wn = [freq(band) , freq(band+1)] ./ Nyq;
                [b,a] = butter(order,Wn);
                signalBands(:, band) = filter(b,a,in);
                
                % Calculate RMS of each band
                sigSquared = signalBands(:,band) .^2;
                sigMean = (1/N) * sum(sigSquared);
                sigRootSquared = sqrt(sigMean);
                bandAmplitudes(1,band) = sigRootSquared;
            end
            
            netInputArray = zeros(1, numOfBands+2);
            
            netInputArray(1,1) = N;
            for band = 1:numOfBands
                netInputArray(1,band+1) = bandAmplitudes(1,band);
            end
            netInputArray(1,7) = rmsIn;
            
        end
        
        function outputArray = net(app, inputArray)
            
            layer01Weights = [];
            layer12Weights = [];
            weights = [layer01Weights, layer12Weights];
            
            hid = dot(inputArray, weights(0));
            
            % activation function for hidden layer
            N = length(hid);
            for n = 1:N
                hid(n) = tanh(hid(n));
            end
            
            pred = dot(hid, weights(1));
            
            outputArray = softmax(pred);
        end
        
        function genre = calcGenre(app, inputArray)
            genre = 'Could not determine'
            switch(inputArray)
                case [1,0,0,0,0,0]
                    genre = 'Rock/Pop/Blues';
                case [0,1,0,0,0,0]
                    genre = 'Classical';
                case [1,0,0,0,0,0]
                    genre = 'Country';
                case [1,0,0,0,0,0]
                    genre = 'Disco';
                case [1,0,0,0,0,0]
                    genre = 'Hip Hop';
                case [1,0,0,0,0,0]
                    genre = 'Jazz';
                otherwise
                    genre = 'Could not determine';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav';'*.mp3'});
            [filename, audio, samplerate] = prepareAudio(app,file,path);
            app.filenameLabel.Text = filename;
            app.Fs = samplerate;
            app.audioIn = audio;
            app.RUNButton.Enable = true;
            plot(app.UIAxes, app.audioIn);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.audioIn,app.Fs);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.genreLabel.setText(calcGenre(app,net(app,processBands(app, app.audioIn, app.Fs))));
 
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 124};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.filenameLabel = uilabel(app.LeftPanel);
            app.filenameLabel.Text = 'filename';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.CenterPanel = uipanel(app.GridLayout);
            app.IdentifiedGenreLabel = uilabel(app.CenterPanel);
            app.IdentifiedGenreLabel.Text = 'Identified Genre:';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '';
            app.genreLabel = uilabel(app.CenterPanel);
            app.genreLabel.Text = 'N/A';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'Time (samples)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntellEQApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end